classdef apptinhdaoham < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        nhpdliuxayaLabel         matlab.ui.control.Label
        hocLabel                 matlab.ui.control.Label
        OhDropDown               matlab.ui.control.DropDown
        OhDropDownLabel          matlab.ui.control.Label
        bchEditField             matlab.ui.control.NumericEditField
        bchEditFieldLabel        matlab.ui.control.Label
        nhphmsEditField          matlab.ui.control.EditField
        nhphmsEditFieldLabel     matlab.ui.control.Label
        chnppDropDown            matlab.ui.control.DropDown
        chnppDropDownLabel       matlab.ui.control.Label
        yaEditField              matlab.ui.control.EditField
        yaEditFieldLabel         matlab.ui.control.Label
        xaEditField              matlab.ui.control.EditField
        xaEditFieldLabel         matlab.ui.control.Label
        ktquEditField            matlab.ui.control.NumericEditField
        ktquEditFieldLabel       matlab.ui.control.Label
        gitrcntnhEditField       matlab.ui.control.NumericEditField
        gitrcntnhEditFieldLabel  matlab.ui.control.Label
    end

    
    methods (Access = private)
        
               function df = tinh_daoham(app,xa, ya, x,a,Oh)

    h = xa(2) - xa(1); % Tinh buoc nhay h
    n = length(xa); % So luong diem du lieu
    df = NaN; % Khoi tao gia tri dao ham voi NaN de kiem tra

    % Kiem tra va tinh gan dung dao ham su dung cong thuc sai phan huu han bac nhat
    for i = 1:n-1

        if xa(i) == x 
        %---------------------- O(h1)----------------------------------%
             if Oh == 1 % O(h1)
                if strcmp(a,'tien')
                        df = (ya(i+1) - ya(i)) / h; % tien
                        break; % Thoat khoi vong lap khi tim thay x
                elseif strcmp(a, 'lui')
                         df = (ya(i) - ya(i-1)) / h; % lui
                           break;
                else strcmp(a,'trung_tam')
                          df = (ya(i+1) - ya(i-1)) / (2*h); % trung tam 
                           break;
                end % end O(h1)
           %--------------------- O(h2)-----------------------------------%
             else Oh == 2 %  O(h2)
                if strcmp(a,'tien')
                        df = (-ya(i+2) + 4*ya(i+1) - 3*ya(i)) / 2*h; % tien
                        break; % Thoat khoi vong lap khi tim thay x
                elseif strcmp(a, 'lui')
                         df = (ya(i+2) - 4*ya(i+1) + 3*ya(i)) / 2*h; % lui
                           break;
                else strcmp(a,'trung_tam')
                          df = (-ya(i+2) + 8*ya(i+1) -8*ya(i-1) + ya(i-2) ) / (12*h); % trung tam 
                           break;
                end % end O(h2)

        end % end  if xa(i) == x

    end % end for i = 1:n-1
   

        end

        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: chnppDropDown
        function chnppDropDownValueChanged(app, event)
            a = app.chnppDropDown.Value;

            xa = app.xaEditField.Value;
            xa = str2num(xa);
            ya = app.yaEditField.Value;
            ya = str2num(ya);
            Oh = str2num(app.OhDropDown.Value);
            x0 = app.gitrcntnhEditField.Value;
            y = char( app.nhphmsEditField.Value);

        if strcmp(a,'ham_so') % khi a=ham_so ta tinh dao ham f theo taylor
            h = app.bchEditField.Value;% khai bao buoc nhay h
            f = str2func(['@(x)' y]);%doi tu y char sang dang ham so 

             if Oh == 1 % O(h1)
            df = (f(x0+h) - f(x0)) / h ;%tinh dao ham f theo Taylor
             else%tinh dao ham f theo Taylor Oh2
            df = (-f(x0+2) + 4*f(x0+1) - 3*f(x0)) / 2*h;
             end

        else % o cac truong hop khac ta dung cac phuong phap tien lui trung tam
          df = tinh_daoham(app,xa, ya, x0,a,Oh)
        end


            app.ktquEditField.Value = df;% hien thi ket qua         
            
        end

        % Value changed function: OhDropDown
        function OhDropDownValueChanged(app, event)
            value = app.OhDropDown.Value;
            a = app.chnppDropDown.Value;
            xa = app.xaEditField.Value;
            xa = str2num(xa);
            ya = app.yaEditField.Value;
            ya = str2num(ya);
            Oh = str2num(value);
            x0 = app.gitrcntnhEditField.Value;
            y = char( app.nhphmsEditField.Value);

        if strcmp(a,'ham_so') % khi a=ham_so ta tinh dao ham f theo taylor
            h = app.bchEditField.Value;% khai bao buoc nhay h
            f = str2func(['@(x)' y]);%doi tu y char sang dang ham so 

             if Oh == 1 %tinh dao ham f theo Taylor Oh1
            df = (f(x0+h) - f(x0)) / h ;
             else %tinh dao ham f theo Taylor Oh2
             df = (-f(x0+2) + 4*f(x0+1) - 3*f(x0)) / 2*h;
             end

        else % o cac truong hop khac ta dung cac phuong phap tien lui trung tam
          df = tinh_daoham(app,xa, ya, x0,a,Oh)
        end


            app.ktquEditField.Value = df;% hien thi ket qua 

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.gitrcntnhEditFieldLabel = uilabel(app.UIFigure);
            app.gitrcntnhEditFieldLabel.Text = 'giá trị cần tính';
            app.gitrcntnhEditField = uieditfield(app.UIFigure, 'numeric');
            app.gitrcntnhEditField.Value = 0.5;
            app.ktquEditFieldLabel = uilabel(app.UIFigure);
            app.ktquEditFieldLabel.Text = 'kết quả';
            app.ktquEditField = uieditfield(app.UIFigure, 'numeric');
            app.ktquEditField.ValueDisplayFormat = '%.10f';
            app.xaEditFieldLabel = uilabel(app.UIFigure);
            app.xaEditFieldLabel.Text = 'xa';
            app.xaEditField = uieditfield(app.UIFigure, 'text');
            app.xaEditField.Value = '0.1 0.3 0.5 0.7 0.9';
            app.yaEditFieldLabel = uilabel(app.UIFigure);
            app.yaEditFieldLabel.Text = 'ya';
            app.yaEditField = uieditfield(app.UIFigure, 'text');
            app.yaEditField.Value = '0.1002 0.3047 0.5236 0.7754 1.1198';
            app.chnppDropDownLabel = uilabel(app.UIFigure);
            app.chnppDropDownLabel.Text = 'chọn pp';
            app.chnppDropDown = uidropdown(app.UIFigure);
            app.chnppDropDown.Items = {'tien', 'lui', 'trung_tam', 'ham_so'};
            app.chnppDropDown.ValueChangedFcn = createCallbackFcn(app, @chnppDropDownValueChanged, true);
            app.chnppDropDown.Value = 'tien';
            app.nhphmsEditFieldLabel = uilabel(app.UIFigure);
            app.nhphmsEditFieldLabel.Text = 'nhập hàm số';
            app.nhphmsEditField = uieditfield(app.UIFigure, 'text');
            app.nhphmsEditField.Value = 'asin(x)';
            app.bchEditFieldLabel = uilabel(app.UIFigure);
            app.bchEditFieldLabel.Text = 'bước h';
            app.bchEditField = uieditfield(app.UIFigure, 'numeric');
            app.bchEditField.Value = 0.2;
            app.OhDropDownLabel = uilabel(app.UIFigure);
            app.OhDropDownLabel.Text = 'O(h)';
            app.OhDropDown = uidropdown(app.UIFigure);
            app.OhDropDown.Items = {'1', '2'};
            app.OhDropDown.ValueChangedFcn = createCallbackFcn(app, @OhDropDownValueChanged, true);
            app.OhDropDown.Value = '1';
            app.hocLabel = uilabel(app.UIFigure);
            app.hocLabel.Text = 'hoặc';
            app.nhpdliuxayaLabel = uilabel(app.UIFigure);
            app.nhpdliuxayaLabel.Text = 'nhập dữ liệu xa, ya';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = apptinhdaoham

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end