classdef lab_tong_hop < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        TmnghimTab                   matlab.ui.container.Tab
        Tn_NhapHamLapEditField       matlab.ui.control.EditField
        NhphmlpPhngphplpLabel        matlab.ui.control.Label
        Tn_KetQuaEditField           matlab.ui.control.EditField
        KtquLabel                    matlab.ui.control.Label
        TimNghiemButton              matlab.ui.control.Button
        Tn_SoLanLapEditField         matlab.ui.control.NumericEditField
        SlnlpEditFieldLabel          matlab.ui.control.Label
        Tn_ChonPhuongPhapDropDown    matlab.ui.control.DropDown
        ChnphngphpDropDownLabel      matlab.ui.control.Label
        Tn_NhapSaiSoEditField        matlab.ui.control.NumericEditField
        NhpsaisEditFieldLabel        matlab.ui.control.Label
        Tn_KhoangPhanLyEditField     matlab.ui.control.EditField
        NhpkhongphnlynghimEditFieldLabel  matlab.ui.control.Label
        Tn_NhapPhuongTrinhEditField  matlab.ui.control.EditField
        NhpphngtrnhEditFieldLabel    matlab.ui.control.Label
        Tn_UIAxes                    matlab.ui.control.UIAxes
        NisuyTab                     matlab.ui.container.Tab
        Ns_DaThucNoiSuyTextArea      matlab.ui.control.TextArea
        KtquathcnisuyLabel           matlab.ui.control.Label
        TinhNoiSuyButton             matlab.ui.control.Button
        Ns_KetQuaEditField           matlab.ui.control.NumericEditField
        KtqunisuyEditFieldLabel      matlab.ui.control.Label
        Ns_GiaTriXEditField          matlab.ui.control.NumericEditField
        GitrcnnisuyLabel             matlab.ui.control.Label
        Ns_PhuongPhapDropDown        matlab.ui.control.DropDown
        ChnphngphpnisuyLabel         matlab.ui.control.Label
        Ns_DuLieuYEditField          matlab.ui.control.EditField
        NhpdliuyEditFieldLabel_3     matlab.ui.control.Label
        Ns_DuLieuXEditField          matlab.ui.control.EditField
        NhpdliuxEditFieldLabel_3     matlab.ui.control.Label
        Ns_UIAxes                    matlab.ui.control.UIAxes
        HiquyTab                     matlab.ui.container.Tab
        Hq_HamHoiQuyTextArea         matlab.ui.control.TextArea
        HmhiquyTextAreaLabel         matlab.ui.control.Label
        TinhHoiQuyButton             matlab.ui.control.Button
        Hq_KetQuaDuDoanEditField     matlab.ui.control.EditField
        KtqudonEditFieldLabel        matlab.ui.control.Label
        Hq_GiaTriDuDoanEditField     matlab.ui.control.NumericEditField
        GitrcndonEditFieldLabel      matlab.ui.control.Label
        Hq_PhuongPhapDropDown        matlab.ui.control.DropDown
        PhngphphiquyDropDownLabel    matlab.ui.control.Label
        Hq_DuLieuYEditField          matlab.ui.control.EditField
        DliuyEditFieldLabel          matlab.ui.control.Label
        Hq_DuLieuXEditField          matlab.ui.control.EditField
        DliuxEditFieldLabel          matlab.ui.control.Label
        Hq_UIAxes                    matlab.ui.control.UIAxes
        ohmTab                       matlab.ui.container.Tab
        KetQuaEditField              matlab.ui.control.NumericEditField
        KtquEditFieldLabel           matlab.ui.control.Label
        TinhDaoHamButton             matlab.ui.control.Button
        HocLabel                     matlab.ui.control.Label
        GiaTriXEditField             matlab.ui.control.NumericEditField
        GitrcntnhohmEditFieldLabel   matlab.ui.control.Label
        PhuongPhapDropDown           matlab.ui.control.DropDown
        ChnphngphpohmDropDownLabel   matlab.ui.control.Label
        SaiSoDropDown                matlab.ui.control.DropDown
        ChngitrsaisDropDownLabel     matlab.ui.control.Label
        BuocHEditField               matlab.ui.control.NumericEditField
        NhpbchEditFieldLabel         matlab.ui.control.Label
        HamSoEditField               matlab.ui.control.EditField
        NhphmsEditFieldLabel         matlab.ui.control.Label
        DuLieuYEditField             matlab.ui.control.EditField
        NhpdliuyEditFieldLabel       matlab.ui.control.Label
        DuLieuXEditField             matlab.ui.control.EditField
        NhpdliuxEditFieldLabel       matlab.ui.control.Label
        TchphnTab                    matlab.ui.container.Tab
        Tp_NhapNEditField            matlab.ui.control.NumericEditField
        NhpNEditFieldLabel           matlab.ui.control.Label
        TinhTichPhanButton           matlab.ui.control.Button
        Tp_CanEditField              matlab.ui.control.EditField
        GiastLabel                   matlab.ui.control.Label
        Tp_KetQuaEditField           matlab.ui.control.NumericEditField
        KtquEditFieldLabel_2         matlab.ui.control.Label
        Tp_PhuongPhapDropDown        matlab.ui.control.DropDown
        ChnphngphptchphnLabel        matlab.ui.control.Label
        Tp_HamSoEditField            matlab.ui.control.EditField
        NhphmsEditFieldLabel_2       matlab.ui.control.Label
        Tp_DuLieuYEditField          matlab.ui.control.EditField
        NhpdliuyEditFieldLabel_2     matlab.ui.control.Label
        Tp_DuLieuXEditField          matlab.ui.control.EditField
        NhpdliuxEditFieldLabel_2     matlab.ui.control.Label
        HocLabel_2                   matlab.ui.control.Label
        GiithiunhmTab                matlab.ui.container.Tab
        TextArea_2                   matlab.ui.control.TextArea
        Label                        matlab.ui.control.Label
        TextArea                     matlab.ui.control.TextArea
        TextAreaLabel                matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function ButtonPushed(app, event)
            disp('Nut tab2');
        end

        % Callback function
        function Button2Pushed(app, event)
            f = @(x) x.^3.*sin(x) + x.*cos(x);
            N = 50;
            a = 0;
            b = 1;
            result = tichphanSimpson(f, a, b, N);
            disp(result);
        end

        % Button pushed function: TinhDaoHamButton
        function TinhDaoHamButtonPushed(app, event)
            % Lấy các tham số từ giao diện người dùng
            f = app.HamSoEditField.Value;
            if ~isempty(f)
                f = str2func(['@(x)' f]);
            end
            h = app.BuocHEditField.Value;
            % Dùng hàm convertStringToNumArray tự viết để chuyển dữ liệu 
            % từ dạng text thành các mảng sổ
            x = convertStringToNumArray(app.DuLieuXEditField.Value);
            y =convertStringToNumArray(app.DuLieuYEditField.Value);
            saiso = str2double(app.SaiSoDropDown.Value);
            phuongphap = app.PhuongPhapDropDown.Value;
            x0 = app.GiaTriXEditField.Value;
            % Tính giá trị đạo hàm
            result = tinhdaoham(f, x,y,x0,h,phuongphap,saiso);
            % Hiển thị kết quả
            app.KetQuaEditField.Value = result;
        end

        % Button pushed function: TinhTichPhanButton
        function TinhTichPhanButtonPushed(app, event)
            % Lấy các tham số từ giao diện người dùng
            f = app.Tp_HamSoEditField.Value;
            if ~isempty(f)
                f = str2func(['@(x)' f]);
            end
            % Dùng hàm convertStringToNumArray tự viết để chuyển dữ liệu 
            % từ dạng text thành các mảng sổ
            xa = convertStringToNumArray(app.Tp_DuLieuXEditField.Value);
            ya =convertStringToNumArray(app.Tp_DuLieuYEditField.Value);
            can_array = convertStringToNumArray(app.Tp_CanEditField.Value);
            a = can_array(1);
            b = can_array(2);
            N = app.Tp_NhapNEditField.Value;
            method = app.Tp_PhuongPhapDropDown.Value;
            % Tính đạo hàm
            result = tinhtichphan(f, a, b, xa, ya, N, method);
            % Hiển thị kết quả ra giao diện người dùng
            app.Tp_KetQuaEditField.Value = result;
        end

        % Button pushed function: TinhNoiSuyButton
        function TinhNoiSuyButtonPushed(app, event)
            % Lấy dữ liệu từ giao diện người dùng
            % Dùng hàm convertStringToNumArray tự viết để chuyển dữ liệu 
            % từ dạng text thành các mảng sổ
            xa = convertStringToNumArray(app.Ns_DuLieuXEditField.Value);
            ya = convertStringToNumArray(app.Ns_DuLieuYEditField.Value);
            x0 = app.Ns_GiaTriXEditField.Value;
            method = app.Ns_PhuongPhapDropDown.Value;

            % Tính đa thức nội suy
            f = tinhnoisuy(xa, ya, method);
            app.Ns_DaThucNoiSuyTextArea.Value = char(f);
            % Tính giá trị nội suy tại giá trị x0
            f_hd = matlabFunction(f);
            f_x0 = f_hd(x0);
            % Hiển thị kết quả ra màn hình
            app.Ns_KetQuaEditField.Value = f_x0;

            % Vẽ đồ thị
            x_plt = linspace(min(xa), max(xa), 1000);
            y_plt = f_hd(x_plt);
            cla((app.Ns_UIAxes));
            % Vẽ đồ thị đa thức nội suy
            plot(app.Ns_UIAxes, x_plt, y_plt, 'LineWidth',2);
            hold(app.Ns_UIAxes, 'on');
            % Vẽ dữ liệu thực tế
            plot(app.Ns_UIAxes, xa, ya, 'ro', 'MarkerSize', 8, 'MarkerFaceColor', 'r');
            hold(app.Ns_UIAxes, 'off')
            % Chú thích cho đồ thị
            title(app.Ns_UIAxes, 'Nội suy và Dữ Liệu Thực'); 
            xlabel(app.Ns_UIAxes, 'x'); 
            ylabel(app.Ns_UIAxes, 'y'); 
            legend(app.Ns_UIAxes, 'Đường nội suy', 'Dữ liệu thực');
        end

        % Button pushed function: TimNghiemButton
        function TimNghiemButtonPushed(app, event)
            f = app.Tn_NhapPhuongTrinhEditField.Value;
            f = str2func(['@(x)' vectorize(f)]);
            saiso = app.Tn_NhapSaiSoEditField.Value;
            khoangphanly = convertStringToNumArray(app.Tn_KhoangPhanLyEditField.Value);
            a = khoangphanly(1);
            b = khoangphanly(2);
            method = app.Tn_ChonPhuongPhapDropDown.Value;
            if (strcmp(method, 'lap'))
                fp = app.Tn_NhapHamLapEditField;
                fp = str2func(['@(x)' fp]);
                [nghiem, solanlap] = timnghiem(fp, a, b, saiso, method);
            else
                [nghiem, solanlap] = timnghiem(f, a, b, saiso, method);
            end
%             app.Tn_KetQuaNghiemEditField.Value = nghiem;
            app.Tn_KetQuaEditField.Value = num2str(nghiem);
            app.Tn_SoLanLapEditField.Value = solanlap;

            % Vẽ đồ thị
            x_plt = linspace(a, b, 1000);
            y_plt = f(x_plt);
            cla((app.Tn_UIAxes));
            plot(app.Tn_UIAxes, x_plt, y_plt, 'LineWidth',2);
            % Chú thích cho đồ thị
            title(app.Tn_UIAxes, 'Hàm số f'); 
            xlabel(app.Tn_UIAxes, 'x'); 
            ylabel(app.Tn_UIAxes, 'y'); 
        end

        % Button pushed function: TinhHoiQuyButton
        function TinhHoiQuyButtonPushed(app, event)
            % Lấy dữ liệu từ giao diện người dùng
            % Dùng hàm convertStringToNumArray tự viết để chuyển dữ liệu 
            % từ dạng text thành các mảng sổ
            xa = convertStringToNumArray(app.Hq_DuLieuXEditField.Value);
            ya = convertStringToNumArray(app.Hq_DuLieuYEditField.Value);
            x0 = app.Hq_GiaTriDuDoanEditField.Value;
            method = app.Hq_PhuongPhapDropDown.Value;
            % Dựa trên method để chọn hàm tính toán phù hợp
            if (strcmp(method, 'tuyentinh'))
                [a1, a0, r2] = HoiQuyTuyenTinh(xa, ya);
                f_hq = @(x) a1.*x + a0;
                % Tạo hàm dạng text để hiển thị ra giao diện người dùng
                f_hq_text = sprintf('y = %.4f * x + %.4f', a1, a0);
            elseif (strcmp(method, 'mu'))
                [a, b, r2] = HoiQuyHamMu(xa,ya);
                f_hq = @(x) a .* x.^b;
                % Tạo hàm dạng text để hiển thị ra giao diện người dùng
                f_hq_text = sprintf('y = %.4f * x ^ %.4f', a, b);
            end
            % Hiển thị kết quả ra giao diện người dùng
            app.Hq_HamHoiQuyTextArea.Value = f_hq_text;
            app.Hq_KetQuaDuDoanEditField.Value = num2str(f_hq(x0));
            % Vẽ đồ thị
            x_plt = linspace(min(xa), max(xa), 1000);
            y_plt = f_hq(x_plt);
            
            cla(app.Hq_UIAxes);
            % Vẽ đồ thị hàm hồi quy
            plot(app.Hq_UIAxes, x_plt, y_plt, '-', 'LineWidth', 2);
            hold(app.Hq_UIAxes, 'on');
            % Vẽ dữ liệu thực tế
            plot(app.Hq_UIAxes, xa, ya, 'ro', 'MarkerSize', 8, 'MarkerFaceColor', 'r');
            hold(app.Hq_UIAxes, 'off')
            % Chú thích cho đồ thị
            title(app.Hq_UIAxes, 'Hồi Quy và Dữ Liệu Thực'); 
            xlabel(app.Hq_UIAxes, 'x'); ylabel(app.Hq_UIAxes, 'y'); 
            legend(app.Hq_UIAxes, 'Đường hồi quy', 'Dữ liệu thực');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TmnghimTab = uitab(app.TabGroup);
            app.TmnghimTab.Title = 'Tìm nghiệm';
            app.Tn_UIAxes = uiaxes(app.TmnghimTab);
            title(app.Tn_UIAxes, 'Title')
            xlabel(app.Tn_UIAxes, 'X')
            ylabel(app.Tn_UIAxes, 'Y')
            zlabel(app.Tn_UIAxes, 'Z')
            app.NhpphngtrnhEditFieldLabel = uilabel(app.TmnghimTab);
            app.NhpphngtrnhEditFieldLabel.Text = 'Nhập phương trình';
            app.Tn_NhapPhuongTrinhEditField = uieditfield(app.TmnghimTab, 'text');
            app.Tn_NhapPhuongTrinhEditField.Value = 'x^2 -4';
            app.NhpkhongphnlynghimEditFieldLabel = uilabel(app.TmnghimTab);
            app.NhpkhongphnlynghimEditFieldLabel.Text = 'Nhập khoảng phân ly nghiệm';
            app.Tn_KhoangPhanLyEditField = uieditfield(app.TmnghimTab, 'text');
            app.Tn_KhoangPhanLyEditField.Value = '1.5, 2.5';
            app.NhpsaisEditFieldLabel = uilabel(app.TmnghimTab);
            app.NhpsaisEditFieldLabel.Text = 'Nhập sai số';
            app.Tn_NhapSaiSoEditField = uieditfield(app.TmnghimTab, 'numeric');
            app.Tn_NhapSaiSoEditField.Value = 0.001;
            app.ChnphngphpDropDownLabel = uilabel(app.TmnghimTab);
            app.ChnphngphpDropDownLabel.Text = 'Chọn phương pháp';
            app.Tn_ChonPhuongPhapDropDown = uidropdown(app.TmnghimTab);
            app.Tn_ChonPhuongPhapDropDown.Items = {'Chia đôi', 'Lặp', 'Tiếp tuyến', 'Dây cung'};
            app.Tn_ChonPhuongPhapDropDown.ItemsData = {'chiadoi', 'lap', 'tieptuyen', 'daycung'};
            app.Tn_ChonPhuongPhapDropDown.Value = 'chiadoi';
            app.SlnlpEditFieldLabel = uilabel(app.TmnghimTab);
            app.SlnlpEditFieldLabel.Text = 'Số lần lặp';
            app.Tn_SoLanLapEditField = uieditfield(app.TmnghimTab, 'numeric');
            app.TimNghiemButton = uibutton(app.TmnghimTab, 'push');
            app.TimNghiemButton.ButtonPushedFcn = createCallbackFcn(app, @TimNghiemButtonPushed, true);
            app.TimNghiemButton.Text = 'Tính toán';
            app.KtquLabel = uilabel(app.TmnghimTab);
            app.KtquLabel.Text = 'Kết quả nghiệm';
            app.Tn_KetQuaEditField = uieditfield(app.TmnghimTab, 'text');
            app.NhphmlpPhngphplpLabel = uilabel(app.TmnghimTab);
            app.NhphmlpPhngphplpLabel.Text = {' Nhập hàm lặp'; '(Phương pháp lặp)'};
            app.Tn_NhapHamLapEditField = uieditfield(app.TmnghimTab, 'text');
            app.Tn_NhapHamLapEditField.Value = 'x^2 -4';
            app.NisuyTab = uitab(app.TabGroup);
            app.NisuyTab.Title = 'Nội suy';
            app.Ns_UIAxes = uiaxes(app.NisuyTab);
            title(app.Ns_UIAxes, 'Title')
            xlabel(app.Ns_UIAxes, 'X')
            ylabel(app.Ns_UIAxes, 'Y')
            zlabel(app.Ns_UIAxes, 'Z')
            app.NhpdliuxEditFieldLabel_3 = uilabel(app.NisuyTab);
            app.NhpdliuxEditFieldLabel_3.Text = 'Nhập dữ liệu x';
            app.Ns_DuLieuXEditField = uieditfield(app.NisuyTab, 'text');
            app.Ns_DuLieuXEditField.Value = '0.1, 0.2, 0.3, 0.4';
            app.NhpdliuyEditFieldLabel_3 = uilabel(app.NisuyTab);
            app.NhpdliuyEditFieldLabel_3.Text = 'Nhập dữ liệu y';
            app.Ns_DuLieuYEditField = uieditfield(app.NisuyTab, 'text');
            app.Ns_DuLieuYEditField.Value = '0.09983, 0.19867, 0.29552, 0.38942';
            app.ChnphngphpnisuyLabel = uilabel(app.NisuyTab);
            app.ChnphngphpnisuyLabel.Text = 'Chọn phương pháp nội suy';
            app.Ns_PhuongPhapDropDown = uidropdown(app.NisuyTab);
            app.Ns_PhuongPhapDropDown.Items = {'Newton TIến', 'Newton Lùi', 'Lagrange'};
            app.Ns_PhuongPhapDropDown.ItemsData = {'newton_tien', 'newton_lui', 'lagrange'};
            app.Ns_PhuongPhapDropDown.Value = 'newton_tien';
            app.GitrcnnisuyLabel = uilabel(app.NisuyTab);
            app.GitrcnnisuyLabel.Text = 'Giá trị cần nội suy';
            app.Ns_GiaTriXEditField = uieditfield(app.NisuyTab, 'numeric');
            app.Ns_GiaTriXEditField.Value = 0.14;
            app.KtqunisuyEditFieldLabel = uilabel(app.NisuyTab);
            app.KtqunisuyEditFieldLabel.Text = 'Kết quả nội suy';
            app.Ns_KetQuaEditField = uieditfield(app.NisuyTab, 'numeric');
            app.TinhNoiSuyButton = uibutton(app.NisuyTab, 'push');
            app.TinhNoiSuyButton.ButtonPushedFcn = createCallbackFcn(app, @TinhNoiSuyButtonPushed, true);
            app.TinhNoiSuyButton.Text = 'Tính toán';
            app.KtquathcnisuyLabel = uilabel(app.NisuyTab);
            app.KtquathcnisuyLabel.Text = 'Đa thức nội suy';
            app.Ns_DaThucNoiSuyTextArea = uitextarea(app.NisuyTab);
            app.HiquyTab = uitab(app.TabGroup);
            app.HiquyTab.Title = 'Hồi quy';
            app.Hq_UIAxes = uiaxes(app.HiquyTab);
            title(app.Hq_UIAxes, 'Đồ thị')
            xlabel(app.Hq_UIAxes, 'X')
            ylabel(app.Hq_UIAxes, 'Y')
            zlabel(app.Hq_UIAxes, 'Z')
            app.DliuxEditFieldLabel = uilabel(app.HiquyTab);
            app.DliuxEditFieldLabel.Text = 'Dữ liệu x';
            app.Hq_DuLieuXEditField = uieditfield(app.HiquyTab, 'text');
            app.Hq_DuLieuXEditField.Value = '6 7 11 15 17 21 23 29 29 37 39';
            app.DliuyEditFieldLabel = uilabel(app.HiquyTab);
            app.DliuyEditFieldLabel.Text = 'Dữ liệu y';
            app.Hq_DuLieuYEditField = uieditfield(app.HiquyTab, 'text');
            app.Hq_DuLieuYEditField.Value = '29 21 29 14 21 15 7 7 13 0 3';
            app.PhngphphiquyDropDownLabel = uilabel(app.HiquyTab);
            app.PhngphphiquyDropDownLabel.Text = 'Phương pháp hồi quy';
            app.Hq_PhuongPhapDropDown = uidropdown(app.HiquyTab);
            app.Hq_PhuongPhapDropDown.Items = {'Tuyến tính', 'Hàm mũ'};
            app.Hq_PhuongPhapDropDown.ItemsData = {'tuyentinh', 'mu'};
            app.Hq_PhuongPhapDropDown.Value = 'tuyentinh';
            app.GitrcndonEditFieldLabel = uilabel(app.HiquyTab);
            app.GitrcndonEditFieldLabel.Text = 'Giá trị cần dự đoán';
            app.Hq_GiaTriDuDoanEditField = uieditfield(app.HiquyTab, 'numeric');
            app.KtqudonEditFieldLabel = uilabel(app.HiquyTab);
            app.KtqudonEditFieldLabel.Text = 'Kết quả dự đoán';
            app.Hq_KetQuaDuDoanEditField = uieditfield(app.HiquyTab, 'text');
            app.TinhHoiQuyButton = uibutton(app.HiquyTab, 'push');
            app.TinhHoiQuyButton.ButtonPushedFcn = createCallbackFcn(app, @TinhHoiQuyButtonPushed, true);
            app.TinhHoiQuyButton.Text = 'Tính toán';
            app.HmhiquyTextAreaLabel = uilabel(app.HiquyTab);
            app.HmhiquyTextAreaLabel.Text = 'Hàm hồi quy';
            app.Hq_HamHoiQuyTextArea = uitextarea(app.HiquyTab);
            app.ohmTab = uitab(app.TabGroup);
            app.ohmTab.Title = 'Đạo hàm';
            app.NhpdliuxEditFieldLabel = uilabel(app.ohmTab);
            app.NhpdliuxEditFieldLabel.Text = 'Nhập dữ liệu x';
            app.DuLieuXEditField = uieditfield(app.ohmTab, 'text');
            app.NhpdliuyEditFieldLabel = uilabel(app.ohmTab);
            app.NhpdliuyEditFieldLabel.Text = 'Nhập dữ liệu y';
            app.DuLieuYEditField = uieditfield(app.ohmTab, 'text');
            app.NhphmsEditFieldLabel = uilabel(app.ohmTab);
            app.NhphmsEditFieldLabel.Text = 'Nhập hàm số';
            app.HamSoEditField = uieditfield(app.ohmTab, 'text');
            app.NhpbchEditFieldLabel = uilabel(app.ohmTab);
            app.NhpbchEditFieldLabel.Text = 'Nhập bước h';
            app.BuocHEditField = uieditfield(app.ohmTab, 'numeric');
            app.BuocHEditField.Value = 0.1;
            app.ChngitrsaisDropDownLabel = uilabel(app.ohmTab);
            app.ChngitrsaisDropDownLabel.Text = 'Chọn giá trị sai số';
            app.SaiSoDropDown = uidropdown(app.ohmTab);
            app.SaiSoDropDown.Items = {'O(h)', 'O(h^2)'};
            app.SaiSoDropDown.ItemsData = {'1', '2'};
            app.SaiSoDropDown.Value = '1';
            app.ChnphngphpohmDropDownLabel = uilabel(app.ohmTab);
            app.ChnphngphpohmDropDownLabel.Text = 'Chọn phương pháp đạo hàm';
            app.PhuongPhapDropDown = uidropdown(app.ohmTab);
            app.PhuongPhapDropDown.Items = {'Xấp xỉ Tiến', 'Xấp xỉ Lùi', 'Xấp xỉ Trung tâm'};
            app.PhuongPhapDropDown.ItemsData = {'tien', 'lui', 'trungtam'};
            app.PhuongPhapDropDown.Value = 'tien';
            app.GitrcntnhohmEditFieldLabel = uilabel(app.ohmTab);
            app.GitrcntnhohmEditFieldLabel.Text = 'Giá trị cần tính đạo hàm';
            app.GiaTriXEditField = uieditfield(app.ohmTab, 'numeric');
            app.HocLabel = uilabel(app.ohmTab);
            app.HocLabel.Text = 'Hoặc';
            app.TinhDaoHamButton = uibutton(app.ohmTab, 'push');
            app.TinhDaoHamButton.ButtonPushedFcn = createCallbackFcn(app, @TinhDaoHamButtonPushed, true);
            app.TinhDaoHamButton.Text = 'Tính toán';
            app.KtquEditFieldLabel = uilabel(app.ohmTab);
            app.KtquEditFieldLabel.Text = 'Kết quả';
            app.KetQuaEditField = uieditfield(app.ohmTab, 'numeric');
            app.TchphnTab = uitab(app.TabGroup);
            app.TchphnTab.Title = 'Tích phân';
            app.HocLabel_2 = uilabel(app.TchphnTab);
            app.HocLabel_2.Text = 'Hoặc';
            app.NhpdliuxEditFieldLabel_2 = uilabel(app.TchphnTab);
            app.NhpdliuxEditFieldLabel_2.Text = 'Nhập dữ liệu x';
            app.Tp_DuLieuXEditField = uieditfield(app.TchphnTab, 'text');
            app.Tp_DuLieuXEditField.Value = '0.1, 0.2, 0.3, 0.4';
            app.NhpdliuyEditFieldLabel_2 = uilabel(app.TchphnTab);
            app.NhpdliuyEditFieldLabel_2.Text = 'Nhập dữ liệu y';
            app.Tp_DuLieuYEditField = uieditfield(app.TchphnTab, 'text');
            app.Tp_DuLieuYEditField.Value = '0.09983, 0.19867, 0.29552, 0.38942';
            app.NhphmsEditFieldLabel_2 = uilabel(app.TchphnTab);
            app.NhphmsEditFieldLabel_2.Text = 'Nhập hàm số';
            app.Tp_HamSoEditField = uieditfield(app.TchphnTab, 'text');
            app.ChnphngphptchphnLabel = uilabel(app.TchphnTab);
            app.ChnphngphptchphnLabel.Text = 'Chọn phương pháp tích phân';
            app.Tp_PhuongPhapDropDown = uidropdown(app.TchphnTab);
            app.Tp_PhuongPhapDropDown.Items = {'Hình thang', 'Simpson 1/3', 'Simpson 3/8'};
            app.Tp_PhuongPhapDropDown.ItemsData = {'hinhthang', 'simpson_13', 'simpson_38'};
            app.Tp_PhuongPhapDropDown.Value = 'hinhthang';
            app.KtquEditFieldLabel_2 = uilabel(app.TchphnTab);
            app.KtquEditFieldLabel_2.Text = 'Kết quả';
            app.Tp_KetQuaEditField = uieditfield(app.TchphnTab, 'numeric');
            app.GiastLabel = uilabel(app.TchphnTab);
            app.GiastLabel.Text = 'Nhập cận để tính tích phân';
            app.Tp_CanEditField = uieditfield(app.TchphnTab, 'text');
            app.Tp_CanEditField.Value = '0.25, 0.35';
            app.TinhTichPhanButton = uibutton(app.TchphnTab, 'push');
            app.TinhTichPhanButton.ButtonPushedFcn = createCallbackFcn(app, @TinhTichPhanButtonPushed, true);
            app.TinhTichPhanButton.Text = 'Tính toán';
            app.NhpNEditFieldLabel = uilabel(app.TchphnTab);
            app.NhpNEditFieldLabel.Text = 'Nhập N';
            app.Tp_NhapNEditField = uieditfield(app.TchphnTab, 'numeric');
            app.GiithiunhmTab = uitab(app.TabGroup);
            app.GiithiunhmTab.Title = 'Giới thiệu nhóm';
            app.TextAreaLabel = uilabel(app.GiithiunhmTab);
            app.TextAreaLabel.Text = '';
            app.TextArea = uitextarea(app.GiithiunhmTab);
            app.TextArea.Value = {'                Thành viên:                                                                   Nhiệm vụ'; '     22200107 - Phạm Thị Ánh Ngân                     Tab Nội suy, làm báo cáo, kiểm tra app'; '     22200108 - Mai Hữu Ngạn                              Tab Đạo hàm, tích phân, tổng hợp app'; '     22200119 - Nguyễn Ngọc Quỳnh Như            Tab Tìm nghiệm, làm báo cáo, kiểm tra app'; '     22200135 - Phạm Phương Quỳnh                  Tab Hồi quy, làm báo cáo, kiểm tra app'};
            app.Label = uilabel(app.GiithiunhmTab);
            app.Label.Text = '';
            app.TextArea_2 = uitextarea(app.GiithiunhmTab);
            app.TextArea_2.Value = {'    GIỚI THIỆU NHÓM'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab_tong_hop

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end